classdef PostProGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NotinuseyetMenu            matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        ImageProcessingTab         matlab.ui.container.Tab
        mainGridImProc             matlab.ui.container.GridLayout
        settingsGrid               matlab.ui.container.GridLayout
        UseFrangiCheckBox          matlab.ui.control.CheckBox
        GridLayout13               matlab.ui.container.GridLayout
        imDepthDisp                matlab.ui.control.UIAxes
        imFiltDisp                 matlab.ui.control.UIAxes
        VesselFilterTab            matlab.ui.container.Tab
        GridLayout43               matlab.ui.container.GridLayout
        GridLayout44               matlab.ui.container.GridLayout
        imFrangiFilt               matlab.ui.control.UIAxes
        imFrangiCombined           matlab.ui.control.UIAxes
        imFrangiScale              matlab.ui.control.UIAxes
        imFrangiFiltIn             matlab.ui.control.UIAxes
        GridLayout45               matlab.ui.container.GridLayout
        FrangiSettingsPanel        matlab.ui.container.Panel
        GridLayout47               matlab.ui.container.GridLayout
        GridLayout48               matlab.ui.container.GridLayout
        GridLayout49_2             matlab.ui.container.GridLayout
        StartScaleEditFieldLabel   matlab.ui.control.Label
        StartScaleEditField        matlab.ui.control.NumericEditField
        GridLayout49_3             matlab.ui.container.GridLayout
        StopScaleEditFieldLabel    matlab.ui.control.Label
        StopScaleEditField         matlab.ui.control.NumericEditField
        ScaleRangeCheckBox         matlab.ui.control.CheckBox
        ManualScalesCheckBox       matlab.ui.control.CheckBox
        GridLayout49_4             matlab.ui.container.GridLayout
        TotalScalesEditFieldLabel  matlab.ui.control.Label
        TotalScalesEditField       matlab.ui.control.NumericEditField
        ScalesTextField            matlab.ui.control.EditField
        GridLayout49               matlab.ui.container.GridLayout
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        InvertedCheckBox           matlab.ui.control.CheckBox
        GridLayout50               matlab.ui.container.GridLayout
        ApplyButton                matlab.ui.control.Button
        SelectFrangiScalesPanel    matlab.ui.container.Panel
        GridLayout46               matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        ExportBatchTab             matlab.ui.container.Tab
        GridLayout14               matlab.ui.container.GridLayout
        ExportPanel                matlab.ui.container.Panel
        GridLayout38               matlab.ui.container.GridLayout
        ExportMapsButton           matlab.ui.control.Button
        expFileName                matlab.ui.control.EditField
        SelectExportPathButton     matlab.ui.control.Button
        expFolderPath              matlab.ui.control.EditField
        ExpOverWrite               matlab.ui.control.CheckBox
        ExpOverview                matlab.ui.control.CheckBox
        GridLayout39               matlab.ui.container.GridLayout
        ExpOverJpg                 matlab.ui.control.CheckBox
        ExpOverTiff                matlab.ui.control.CheckBox
        GridLayout39_2             matlab.ui.container.GridLayout
        ExpNativeTiff              matlab.ui.control.CheckBox
        ExpNativeJpg               matlab.ui.control.CheckBox
        GridLayout39_3             matlab.ui.container.GridLayout
        ExpOverPdf                 matlab.ui.control.CheckBox
        ExpOverPng                 matlab.ui.control.CheckBox
        GridLayout39_4             matlab.ui.container.GridLayout
        ExpNativePng               matlab.ui.control.CheckBox
        GridLayout39_5             matlab.ui.container.GridLayout
        ExpNative                  matlab.ui.control.CheckBox
        ExpDepthMap                matlab.ui.control.CheckBox
        GridLayout40               matlab.ui.container.GridLayout
        ExpMatFile                 matlab.ui.control.CheckBox
        ExpLogFile                 matlab.ui.control.CheckBox
        BatchProcessingPanel       matlab.ui.container.Panel
        GridLayout38_2             matlab.ui.container.GridLayout
        BrowseFolderButton         matlab.ui.control.Button
        batchProcessFolder         matlab.ui.control.EditField
        BatchProcessButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExportMapsButton
        function ExportMapsButtonPushed(app, event)
            app.PPA.Export();
        end

        % Button pushed function: SelectExportPathButton
        function SelectExportPathButtonPushed(app, event)
            [fileName,folder] = uigetfile('C:\Data');
            if fileName
                selectedFilePath = [folder fileName];
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            app.PPA.exportPath = selectedFilePath;
            app.expFolderPath.Value = selectedFilePath;
        end

        % Value changed function: ExpOverview
        function ExpOverviewValueChanged(app, event)
            app.ExpOverJpg.Enable = app.ExpOverview.Value;
            app.ExpOverTiff.Enable = app.ExpOverview.Value;
            app.ExpOverPdf.Enable = app.ExpOverview.Value;
            app.ExpOverPng.Enable = app.ExpOverview.Value;
        end

        % Value changed function: ExpNative
        function ExpNativeValueChanged(app, event)
            app.ExpNativePng.Enable = app.ExpNative.Value;
            app.ExpNativeTiff.Enable = app.ExpNative.Value;
            app.ExpNativeJpg.Enable = app.ExpNative.Value;
            app.ExpDepthMap.Enable = app.ExpNative.Value;
        end

        % Value changed function: ScalesTextField, 
        % StartScaleEditField, StopScaleEditField, 
        % TotalScalesEditField
        function UpdateScaleTable(app, event)
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ScaleRangeCheckBox
        function ScaleRangeCheckBoxValueChanged(app, event)
            app.ManualScalesCheckBox.Value = ~app.ScaleRangeCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ManualScalesCheckBox
        function ManualScalesCheckBoxValueChanged(app, event)
            app.ScaleRangeCheckBox.Value = ~app.ManualScalesCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Callback function: UITable, UITable
        function FrangiTableSelected(app, event)
            selectedRow = event.Indices(1);
            app.PPA.Plot_Frangi(true,selectedRow);
        end

        % Display data changed function: UITable
        function FrangiTableChanged(app, event)
            app.PPA.Update_Frangi_Combo();
            app.PPA.Plot_Frangi(false);
        end

        % Value changed function: UseFrangiCheckBox
        function UseFrangiCheckBoxValueChanged(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.PPA.Apply_Frangi();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FOAM Post Pro App';
            app.NotinuseyetMenu = uimenu(app.UIFigure);
            app.NotinuseyetMenu.Enable = 'off';
            app.NotinuseyetMenu.Text = 'Not in use yet';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.mainGridImProc = uigridlayout(app.ImageProcessingTab);
            app.settingsGrid = uigridlayout(app.mainGridImProc);
            app.UseFrangiCheckBox = uicheckbox(app.settingsGrid);
            app.UseFrangiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFrangiCheckBoxValueChanged, true);
            app.UseFrangiCheckBox.Text = 'Use Frangi';
            app.GridLayout13 = uigridlayout(app.mainGridImProc);
            app.imDepthDisp = uiaxes(app.GridLayout13);
            title(app.imDepthDisp, '')
            xlabel(app.imDepthDisp, '')
            ylabel(app.imDepthDisp, '')
            app.imFiltDisp = uiaxes(app.GridLayout13);
            title(app.imFiltDisp, '')
            app.VesselFilterTab = uitab(app.TabGroup);
            app.VesselFilterTab.Title = 'Vessel Filter';
            app.GridLayout43 = uigridlayout(app.VesselFilterTab);
            app.GridLayout44 = uigridlayout(app.GridLayout43);
            app.imFrangiFilt = uiaxes(app.GridLayout44);
            title(app.imFrangiFilt, 'Frangi Filtered')
            app.imFrangiFilt.XTick = [];
            app.imFrangiFilt.YTick = [];
            app.imFrangiCombined = uiaxes(app.GridLayout44);
            title(app.imFrangiCombined, 'Combined')
            app.imFrangiCombined.XTick = [];
            app.imFrangiCombined.YTick = [];
            app.imFrangiScale = uiaxes(app.GridLayout44);
            title(app.imFrangiScale, {'Scale'; ''})
            app.imFrangiScale.XTick = [];
            app.imFrangiScale.YTick = [];
            app.imFrangiFiltIn = uiaxes(app.GridLayout44);
            title(app.imFrangiFiltIn, {'Filter Input'; ''})
            app.imFrangiFiltIn.XTick = [];
            app.imFrangiFiltIn.YTick = [];
            app.GridLayout45 = uigridlayout(app.GridLayout43);
            app.FrangiSettingsPanel = uipanel(app.GridLayout45);
            app.FrangiSettingsPanel.Title = 'Frangi Settings';
            app.GridLayout47 = uigridlayout(app.FrangiSettingsPanel);
            app.GridLayout48 = uigridlayout(app.GridLayout47);
            app.GridLayout49_2 = uigridlayout(app.GridLayout48);
            app.StartScaleEditFieldLabel = uilabel(app.GridLayout49_2);
            app.StartScaleEditFieldLabel.Text = 'Start Scale';
            app.StartScaleEditField = uieditfield(app.GridLayout49_2, 'numeric');
            app.StartScaleEditField.Limits = [0.01 200];
            app.StartScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StartScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StartScaleEditField.Value = 1;
            app.GridLayout49_3 = uigridlayout(app.GridLayout48);
            app.StopScaleEditFieldLabel = uilabel(app.GridLayout49_3);
            app.StopScaleEditFieldLabel.Text = 'Stop Scale';
            app.StopScaleEditField = uieditfield(app.GridLayout49_3, 'numeric');
            app.StopScaleEditField.Limits = [1 500];
            app.StopScaleEditField.RoundFractionalValues = 'on';
            app.StopScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StopScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StopScaleEditField.Value = 20;
            app.ScaleRangeCheckBox = uicheckbox(app.GridLayout48);
            app.ScaleRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleRangeCheckBoxValueChanged, true);
            app.ScaleRangeCheckBox.Text = 'Scale Range';
            app.ScaleRangeCheckBox.Value = true;
            app.ManualScalesCheckBox = uicheckbox(app.GridLayout48);
            app.ManualScalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualScalesCheckBoxValueChanged, true);
            app.ManualScalesCheckBox.Text = 'Manual Scales';
            app.GridLayout49_4 = uigridlayout(app.GridLayout48);
            app.TotalScalesEditFieldLabel = uilabel(app.GridLayout49_4);
            app.TotalScalesEditFieldLabel.Text = 'Total Scales';
            app.TotalScalesEditField = uieditfield(app.GridLayout49_4, 'numeric');
            app.TotalScalesEditField.Limits = [1 1000];
            app.TotalScalesEditField.RoundFractionalValues = 'on';
            app.TotalScalesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.TotalScalesEditField.Value = 5;
            app.ScalesTextField = uieditfield(app.GridLayout48, 'text');
            app.ScalesTextField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.ScalesTextField.Value = '1 5 10';
            app.GridLayout49 = uigridlayout(app.GridLayout48);
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout49);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.GridLayout49, 'numeric');
            app.SensitivityEditField.Limits = [0.001 100];
            app.SensitivityEditField.ValueDisplayFormat = '%4.2f';
            app.SensitivityEditField.Value = 0.05;
            app.InvertedCheckBox = uicheckbox(app.GridLayout48);
            app.InvertedCheckBox.Text = 'Inverted?';
            app.GridLayout50 = uigridlayout(app.GridLayout47);
            app.ApplyButton = uibutton(app.GridLayout50, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectFrangiScalesPanel = uipanel(app.GridLayout45);
            app.SelectFrangiScalesPanel.Title = 'Select Frangi Scales';
            app.GridLayout46 = uigridlayout(app.SelectFrangiScalesPanel);
            app.UITable = uitable(app.GridLayout46);
            app.UITable.ColumnName = {'scale'; 'selected'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @FrangiTableChanged, true);
            app.ExportBatchTab = uitab(app.TabGroup);
            app.ExportBatchTab.Title = 'Export & Batch';
            app.GridLayout14 = uigridlayout(app.ExportBatchTab);
            app.ExportPanel = uipanel(app.GridLayout14);
            app.ExportPanel.Title = 'Export';
            app.GridLayout38 = uigridlayout(app.ExportPanel);
            app.ExportMapsButton = uibutton(app.GridLayout38, 'push');
            app.ExportMapsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMapsButtonPushed, true);
            app.ExportMapsButton.Text = 'Export Maps';
            app.expFileName = uieditfield(app.GridLayout38, 'text');
            app.expFileName.Value = 'exportName';
            app.SelectExportPathButton = uibutton(app.GridLayout38, 'push');
            app.SelectExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExportPathButtonPushed, true);
            app.SelectExportPathButton.Text = 'Select Export Path';
            app.expFolderPath = uieditfield(app.GridLayout38, 'text');
            app.expFolderPath.Editable = 'off';
            app.expFolderPath.Value = 'exportPath';
            app.ExpOverWrite = uicheckbox(app.GridLayout38);
            app.ExpOverWrite.Text = 'Overwrite?';
            app.ExpOverview = uicheckbox(app.GridLayout38);
            app.ExpOverview.ValueChangedFcn = createCallbackFcn(app, @ExpOverviewValueChanged, true);
            app.ExpOverview.Tooltip = {'Create side-by-side overviews of mip and depth map including colorbar. '};
            app.ExpOverview.Text = 'Overview';
            app.ExpOverview.Value = true;
            app.GridLayout39 = uigridlayout(app.GridLayout38);
            app.ExpOverJpg = uicheckbox(app.GridLayout39);
            app.ExpOverJpg.Text = '.jpg';
            app.ExpOverJpg.Value = true;
            app.ExpOverTiff = uicheckbox(app.GridLayout39);
            app.ExpOverTiff.Text = '.tiff';
            app.GridLayout39_2 = uigridlayout(app.GridLayout38);
            app.ExpNativeTiff = uicheckbox(app.GridLayout39_2);
            app.ExpNativeTiff.Enable = 'off';
            app.ExpNativeTiff.Text = '.tiff';
            app.ExpNativeJpg = uicheckbox(app.GridLayout39_2);
            app.ExpNativeJpg.Enable = 'off';
            app.ExpNativeJpg.Text = '.jpg';
            app.GridLayout39_3 = uigridlayout(app.GridLayout38);
            app.ExpOverPdf = uicheckbox(app.GridLayout39_3);
            app.ExpOverPdf.Text = '.pdf';
            app.ExpOverPdf.Value = true;
            app.ExpOverPng = uicheckbox(app.GridLayout39_3);
            app.ExpOverPng.Text = '.png';
            app.GridLayout39_4 = uigridlayout(app.GridLayout38);
            app.ExpNativePng = uicheckbox(app.GridLayout39_4);
            app.ExpNativePng.Enable = 'off';
            app.ExpNativePng.Text = '.png';
            app.GridLayout39_5 = uigridlayout(app.GridLayout38);
            app.ExpNative = uicheckbox(app.GridLayout39_5);
            app.ExpNative.ValueChangedFcn = createCallbackFcn(app, @ExpNativeValueChanged, true);
            app.ExpNative.Tooltip = {'Create native resolution RGB images (without color bar)?'};
            app.ExpNative.Text = 'Native';
            app.ExpDepthMap = uicheckbox(app.GridLayout39_5);
            app.ExpDepthMap.Enable = 'off';
            app.ExpDepthMap.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.ExpDepthMap.Text = 'Depth Map';
            app.ExpDepthMap.Value = true;
            app.GridLayout40 = uigridlayout(app.GridLayout38);
            app.ExpMatFile = uicheckbox(app.GridLayout40);
            app.ExpMatFile.Text = 'Export .mat';
            app.ExpLogFile = uicheckbox(app.GridLayout40);
            app.ExpLogFile.Text = 'Export log file';
            app.ExpLogFile.Value = true;
            app.BatchProcessingPanel = uipanel(app.GridLayout14);
            app.BatchProcessingPanel.Title = 'Batch Processing';
            app.GridLayout38_2 = uigridlayout(app.BatchProcessingPanel);
            app.BrowseFolderButton = uibutton(app.GridLayout38_2, 'push');
            app.BrowseFolderButton.Text = 'Browse Folder';
            app.batchProcessFolder = uieditfield(app.GridLayout38_2, 'text');
            app.batchProcessFolder.Value = 'exportName';
            app.BatchProcessButton = uibutton(app.GridLayout38_2, 'push');
            app.BatchProcessButton.Text = 'Batch Process!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PostProGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end